classdef scopeApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MeasurementappUIFigure   matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        CINIlogo                 matlab.ui.control.Image
        KULeuvenLogo             matlab.ui.control.Image
        clearMeasurementsButton  matlab.ui.control.Button
        GetmeasurementButton     matlab.ui.control.Button
        Tree                     matlab.ui.container.Tree
        OpenfolderButton         matlab.ui.control.Button
        PlotPanel                matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        Foldertree               matlab.ui.container.TreeNode
        Node                     matlab.ui.container.TreeNode
        ChannelSelection         matlab.ui.control.Label
        plotChoices                 %matlab.ui.control.CheckBox
        %         measuremetDoneLabel      matlab.ui.control.Label
        saveBox                  matlab.ui.control.CheckBox
        PlotButton               matlab.ui.control.Button
        FFTButton                matlab.ui.control.Button
        FilenameEditFieldLabel   matlab.ui.control.Label
        FilenameEditField        matlab.ui.control.EditField
        plotTitleEditFieldLabel  matlab.ui.control.Label
        plotTitleEditField       matlab.ui.control.EditField
        startPlotPacketLabel     matlab.ui.control.Label
        startPlotPacketNr        matlab.ui.control.NumericEditField
        endPlotPacketLabel       matlab.ui.control.Label
        endPlotPacketNr          matlab.ui.control.NumericEditField
        decodeDoneLabel          matlab.ui.control.Label
        DecodePanel              matlab.ui.container.Panel
        pnDecodePanel            matlab.ui.container.Panel
        pnDecodeBox              matlab.ui.control.CheckBox
        speDecodePanel           matlab.ui.container.Panel
        speDecodeBox             matlab.ui.control.CheckBox
        PBDecodePanel           matlab.ui.container.Panel
        PBDecodeBox             matlab.ui.control.CheckBox
        PBplotButton             matlab.ui.control.Button
        DecodeButton             matlab.ui.control.Button
        PNplotButton             matlab.ui.control.Button
        ethAnalyzeButton         matlab.ui.control.Button
        DropDownSelectMeasurementEth  matlab.ui.control.DropDown
        LabelSelectMeasurementEth  matlab.ui.control.Label
        pcapQuickAnalyzeButton         matlab.ui.control.Button
        InformationPanel              matlab.ui.container.Panel
        Filename                      matlab.ui.control.Label
        SampleintervalEditField       matlab.ui.control.NumericEditField
        SampleintervalEditFieldLabel  matlab.ui.control.Label
        EndtimeEditField              matlab.ui.control.NumericEditField
        EndtimeEditFieldLabel         matlab.ui.control.Label
        StarttimeEditField            matlab.ui.control.NumericEditField
        StarttimeEditFieldLabel       matlab.ui.control.Label
    
        RecordLengthEditField    matlab.ui.control.NumericEditField
        RecordLengthEditFieldLabel  matlab.ui.control.Label
        pcapLamp                      matlab.ui.control.Lamp
        pcapLampLabel                 matlab.ui.control.Label
        scopeLamp                     matlab.ui.control.Lamp
        scopeLampLabel                matlab.ui.control.Label
        FilenameLabel                 matlab.ui.control.Label
        channelPlotRangeLabel         matlab.ui.control.Label
        mathPlotRangeLabel         matlab.ui.control.Label
        
        % plot panel
        PlotPanel_2           matlab.ui.container.Panel
        channelPanel           matlab.ui.container.Panel
        mathPanel             matlab.ui.container.Panel  
        EndtimeSpinner        matlab.ui.control.Spinner
        EndtimeLabel          matlab.ui.control.Label
        MathEndtimeSpinner        matlab.ui.control.Spinner
        MathEndtimeLabel          matlab.ui.control.Label
        StarttimeSpinner      matlab.ui.control.Spinner
        StarttimeLabel        matlab.ui.control.Label
        MathStarttimeSpinner      matlab.ui.control.Spinner
        MathStarttimeLabel matlab.ui.control.Label
        DropDownSelectMeasurementPlot  matlab.ui.control.DropDown
        LabelSelectMeasurementPlot  matlab.ui.control.Label
        % FFT panel
        FFTPanel           matlab.ui.container.Panel
        FFTEndtimeSpinner        matlab.ui.control.Spinner
        FFTEndtimeLabel          matlab.ui.control.Label
        FFTStarttimeSpinner      matlab.ui.control.Spinner
        FFTStarttimeLabel        matlab.ui.control.Label
        
        % Quick analyze panel
        QuickpcapanalyzerPanel         matlab.ui.container.Panel
        analyzeButton                  matlab.ui.control.Button        
        EnableHistogramSwitch       matlab.ui.control.Switch
        EnableHistogramSwitchLabel  matlab.ui.control.Label
        EnabledistributionSwitch       matlab.ui.control.Switch
        EnabledistributionSwitchLabel  matlab.ui.control.Label
        DirectionCPUSwitch             matlab.ui.control.Switch
        DirectionCPUSwitchLabel        matlab.ui.control.Label
        Tap2portsEditField             matlab.ui.control.EditField
        Tap2portsEditFieldLabel        matlab.ui.control.Label
        Tap1portsEditField             matlab.ui.control.EditField
        Tap1portsEditFieldLabel        matlab.ui.control.Label
        BinSizeEditField               matlab.ui.control.NumericEditField
        BinSizeEditFieldLabel          matlab.ui.control.Label
        DirectionalSwitch              matlab.ui.control.Switch
        DirectionalSwitchLabel         matlab.ui.control.Label
        DeviceNameEditFieldLabel   matlab.ui.control.Label
        DeviceNameEditField        matlab.ui.control.EditField

        WindowDropDown        matlab.ui.control.DropDown
        WindowDropDownLabel             matlab.ui.control.Label
        ethernetTableButton            matlab.ui.control.Button 
        pbTableButton            matlab.ui.control.Button

        files
        folder
        selectedFile = {}
        chosenFileId = 1

        networkAnalyzerApp
        ethHeight = 2
        ethColor ='b'
        ax
        l
        screenSize 
        screenWidth 
        screenHeight 
    end
    
    properties(Access = public)
        measurementObj
    end
    methods (Access = private)

        function lMoving(~, source)
            % Always keep the position rounded to 1 decimal place
            source.Position(1,1) = round(source.Position(1,1),1);
            source.Position(2,1) = round(source.Position(2,1),1);
            % Add any other action here triggered by the movement of the sliders
        end

        function plotting(app,src,event)
            c=0;
            m=0;
            for i= 1 : numel(app.plotChoices)
                if app.plotChoices(i).checkbox.Value
                    if contains(lower(app.plotChoices(i).checkbox.Text),'ch')
                        c=c+1;
                        PlotCH(1,c) = str2double(cell2mat(extractAfter(lower(app.plotChoices(i).checkbox.Text),'ch')));
                        plotChTitle(c,1) = string(app.plotChoices(i).TitleEditField.Value);
                    end
                    if contains(lower(app.plotChoices(i).checkbox.Text),'math')
                        m=m+1;
                        PlotMath(1,m) = str2double(cell2mat(extractAfter(lower(app.plotChoices(i).checkbox.Text),'math')));
                        plotMathTitle(m,1) =  string(app.plotChoices(i).TitleEditField.Value);
                    end

                    if contains(lower(app.plotChoices(i).checkbox.Text),'mth')
                        m=m+1;
                        PlotMath(1,m) = str2double(cell2mat(extractAfter(lower(app.plotChoices(i).checkbox.Text),'mth')));
                        if isnan(PlotMath(1,m))
                            PlotMath(1,m) = m;
                        end
                        plotMathTitle(m,1) =  string(app.plotChoices(i).TitleEditField.Value);
                    end
                end

            end
            if app.saveBox.Value
                savePlotEn = 'save';
                savePlotTitle = app.FilenameEditField.Value;
            else
                savePlotEn = [];
                savePlotTitle = [];
            end

            if c>0
                app.measurementObj{app.chosenFileId}.plotChannels('channels',PlotCH,savePlotEn,savePlotTitle,'titles',plotChTitle,'limit',[app.StarttimeSpinner.Value app.EndtimeSpinner.Value]);
            end
            if m>0
                app.measurementObj{app.chosenFileId}.plotMath('channels',PlotMath,savePlotEn,savePlotTitle,'titles',plotMathTitle);
            end
        end

        function plotFFT(app,src,event)
            %             set(app.MeasurementappUIFigure,'Pointer','watch'); drawnow;

            %             app.measurementObj.fft();
            c=0;
            m=0;
            for i= 1 : numel(app.plotChoices)
                if app.plotChoices(i).checkbox.Value
                    if contains(lower(app.plotChoices(i).checkbox.Text),'ch')
                        c=c+1;
                        PlotCH(1,c) = str2double(cell2mat(extractAfter(lower(app.plotChoices(i).checkbox.Text),'ch')));
                        plotChTitle(c,1) = string(app.plotChoices(i).TitleEditField.Value);
                    end
                    if contains(lower(app.plotChoices(i).checkbox.Text),'math')
                        m=m+1;
                        PlotMath(1,m) = str2double(cell2mat(extractAfter(lower(app.plotChoices(i).checkbox.Text),'math')));
                        plotMathTitle(m,1) =  string(app.plotChoices(i).TitleEditField.Value);
                    end

                    if contains(lower(app.plotChoices(i).checkbox.Text),'mth')
                        m=m+1;
                        PlotMath(1,m) = str2double(cell2mat(extractAfter(lower(app.plotChoices(i).checkbox.Text),'mth')));
                        if isnan(PlotMath(1,m))
                            PlotMath(1,m) = m;
                        end
                        plotMathTitle(m,1) =  string(app.plotChoices(i).TitleEditField.Value);
                    end
                end
            end
            
            if app.saveBox.Value
                savePlotEn = 'save';
                savePlotTitle = app.FilenameEditField.Value;
            else
                savePlotEn = [];
                savePlotTitle = [];
            end

            if c>0
                app.measurementObj{app.chosenFileId}.advancedFFT('channels',PlotCH,savePlotEn,savePlotTitle,'windowtype', app.WindowDropDown.Value,'titles',plotChTitle,'limit',[app.FFTStarttimeSpinner.Value app.FFTEndtimeSpinner.Value]);
            end

            %             set(app.MeasurementappUIFigure,'Pointer','arrow'); drawnow;
        end

        function decode(app,src,event)
            set(app.MeasurementappUIFigure,'Pointer','watch'); drawnow;

            toggleComponents(app,'Off')
            c=0;
            decodeCH = [];
            for i= 1 : numel(app.pnDecodeBox)
                if app.pnDecodeBox(i).Value
                    if contains(lower(app.pnDecodeBox(i).Text),'ch')
                        c=c+1;
                        decodeCH(1,c) = str2double(cell2mat(extractAfter(lower(app.pnDecodeBox(i).Text),'ch')));
                    end
                end
            end
            
            if ~isempty(decodeCH)
                app.measurementObj{app.chosenFileId} = app.measurementObj{app.chosenFileId}.decodePN(decodeCH);

                maxPacketsmax = 0;
                for i= 1 : numel(app.pnDecodeBox)
                    if app.pnDecodeBox(i).Value
                        if contains(lower(app.pnDecodeBox(i).Text),'ch')
                            maxPackets  = max([maxPacketsmax numel(app.measurementObj{app.chosenFileId}.channels(i).pn)]);
                        end
                    end
                end

                % Create FilenameEditFieldLabel
                app.decodeDoneLabel = uilabel(app.DecodePanel);
                %             app.pnDecodeLabel.HorizontalAlignment = 'right';
                app.decodeDoneLabel.Position = [10 60 62 22];
                app.decodeDoneLabel.Text = 'Done:';
                toggleComponents(app,'On')
                % Create packet range Field
                app.startPlotPacketLabel = uilabel(app.DecodePanel);
                app.startPlotPacketLabel.Position = [20 120 100 22];
                app.startPlotPacketLabel.Text = 'Plot packets from';
                app.startPlotPacketNr = uieditfield(app.DecodePanel, 'numeric');
                app.startPlotPacketNr.Position = [120 120 50 22];

                app.endPlotPacketLabel = uilabel(app.DecodePanel);
                app.endPlotPacketLabel.Position = [180 120 20 22];
                app.endPlotPacketLabel.Text = 'to';
                app.endPlotPacketNr = uieditfield(app.DecodePanel, 'numeric');
                app.endPlotPacketNr.Position = [200 120 50 22];
                app.endPlotPacketNr.Value= maxPackets;
                % Create plot PN button
                app.PNplotButton = uibutton(app.DecodePanel, 'push');
                app.PNplotButton.Position = [28 90 100 22];
                app.PNplotButton.Text = 'plot';
                app.PNplotButton.ButtonPushedFcn = @app.PNplot;
                
            end
            %% SPE section
            decodeSPE = [];
            c=0;
            for i= 1 : numel(app.speDecodeBox)
                if app.speDecodeBox(i).Value
                    if contains(lower(app.speDecodeBox(i).Text),'ch')
                        c=c+1;
                        decodeSPE(1,c) = str2double(cell2mat(extractAfter(lower(app.speDecodeBox(i).Text),'ch')));
                    end
                end
            end
            if ~isempty(decodeSPE)
                addpath('F:\Documents\1.projects\SPE-decoding-matlab')
                SPEdecoding(app.measurementObj,decodeSPE(1))
                app.ethernetTableButton = uibutton(app.DecodePanel, 'push');
                app.ethernetTableButton.Position = [135 90 100 22];
                app.ethernetTableButton.Text = 'table';
                app.ethernetTableButton.ButtonPushedFcn = @app.ethernetTable;

            end
            
                %% PB

            decodePB = [];
            c=0;
            for i= 1 : numel(app.PBDecodeBox)
                if app.PBDecodeBox(i).Value
                    if contains(lower(app.PBDecodeBox(i).Text),'ch')
                        c=c+1;
                        decodePB(1,c) = str2double(cell2mat(extractAfter(lower(app.PBDecodeBox(i).Text),'ch')));
                    end
                end
            end
            if ~isempty(decodePB)
                app.measurementObj{app.chosenFileId} = app.measurementObj{app.chosenFileId}.decodePB(decodePB);
            end

            app.PBplotButton = uibutton(app.DecodePanel, 'push');
                app.PBplotButton.Position = [28 90 100 22];
                app.PBplotButton.Text = 'plot';
                app.PBplotButton.ButtonPushedFcn = @app.PBplot;
                

            app.pbTableButton = uibutton(app.DecodePanel, 'push');
                app.pbTableButton.Position = [135 90 100 22];
                app.pbTableButton.Text = 'table';
                app.pbTableButton.ButtonPushedFcn = @app.pbTable;
            assignin('base',"measurementObject",app.measurementObj);
            set(app.MeasurementappUIFigure,'Pointer','arrow'); drawnow;
            toggleComponents(app,'On');
            
        end

        function PNplot(app,src,event)
            figure;
            fontSize= 20;
            set(gca,'fontsize',fontSize+2) % set fontsize of the plot to 20
            set(gcf,'units','normalized','outerposition',[0 0 1 1]) % full screen
            set(0, 'DefaultAxesFontSize', fontSize);
            subplotNr =1
            for i= 1 : numel(app.pnDecodeBox)
                if app.pnDecodeBox(i).Value
                    LastID = find(app.pnDecodeBox(i).Text==' ',1,'last');
                    index = str2double(app.pnDecodeBox(i).Text(LastID+1:end));
                    subplotArray(subplotNr) = subplot(sum([app.pnDecodeBox.Value]),1,subplotNr);
                    app.measurementObj{app.chosenFileId}.channels(index).pn.plot(0,1,app.ethHeight,app.ethColor);
                    ylabel(['channel ' num2str(index)]);
                    subplotNr = subplotNr+1;
                end

            end
            sgt=sgtitle('Ethernet packets');
            sgt.FontSize = 20;
            sgt.FontWeight = 'bold';
            linkaxes(subplotArray,'x')
        end

        function PBplot(app,src,event)
            figure;
            fontSize= 20;
            set(gca,'fontsize',fontSize+2) % set fontsize of the plot to 20
            set(gcf,'units','normalized','outerposition',[0 0 1 1]) % full screen
            set(0, 'DefaultAxesFontSize', fontSize);
            subplotNr =1
            
            for i =1:length(app.measurementObj{app.chosenFileId}.channels)
                if isprop(app.measurementObj{app.chosenFileId}.channels(i),'pb')
                    
                    subplotArray(subplotNr) = subplot(length(app.measurementObj{app.chosenFileId}.channels),1,subplotNr);
                    app.measurementObj{app.chosenFileId}.channels(i).pb.plot(0,1,2);
                    ylabel(app.measurementObj{app.chosenFileId}.channels(i).name);
                    subplotNr = subplotNr+1;
                end

            end
            sgt=sgtitle('PROFIBUS packets');
            sgt.FontSize = 20;
            sgt.FontWeight = 'bold';
            linkaxes(subplotArray,'x')
        end

        function pcapPlot(app,src,event)
            if sum([app.pnDecodeBox.Value]) >0
                figure;
                fontSize= 20;
                set(gca,'fontsize',fontSize+2) % set fontsize of the plot to 20
                set(gcf,'units','normalized','outerposition',[0 0 1 1]) % full screen
                set(0, 'DefaultAxesFontSize', fontSize);
                subplotNr=1;
                for i= 1 : numel(app.pnDecodeBox)
                    if app.pnDecodeBox(i).Value
                        LastID = find(app.pnDecodeBox(i).Text==' ',1,'last');
                        index = str2double(app.pnDecodeBox(i).Text(LastID+1:end));
                        subplotArray(subplotNr) = subplot(sum([app.pnDecodeBox.Value]),1,subplotNr);
                        app.measurementObj{app.chosenFileId}.port(index+1).packet.plot(0,1,app.ethHeight,app.ethColor,app.startPlotPacketNr.Value,app.endPlotPacketNr.Value);
                        subplotNr = subplotNr+1;
                        ylabel(['port ' num2str(index)]);
                    end
                end
                sgt = sgtitle('Ethernet packets');
                sgt.FontSize = 20;
                sgt.FontWeight = 'bold';
                linkaxes(subplotArray,'x');
            end
        end

        function plotEnable(app,src,event,x)
            % Create FilenameEditFieldLabel

            if event.Value == 1
                pos = event.Source.Position;
                pos(1) = pos(1)+50;
                pos(3:4) = [62 22];
                app.plotChoices(x).titleLabel = uilabel(app.PlotPanel);
                app.plotChoices(x).titleLabel.HorizontalAlignment = 'right';
                app.plotChoices(x).titleLabel.Position = pos;
                app.plotChoices(x).titleLabel.Text = 'Plot title:';

                % Create FilenameEditField
                app.plotChoices(x).TitleEditField = uieditfield(app.PlotPanel, 'text');
                posEditField = pos(1)+65;

                app.plotChoices(x).TitleEditField.Position = [posEditField pos(2) 150 22];
            else
                if ~isempty(app.plotChoices(x).titleLabel)
                    app.plotChoices(x).titleLabel.delete
                    app.plotChoices(x).TitleEditField.delete;
                end
            end
        end

        function saveOption(app,src,event)
            if event.Value
                % Create FilenameEditFieldLabel
                app.FilenameEditFieldLabel = uilabel(app.PlotPanel);
                app.FilenameEditFieldLabel.HorizontalAlignment = 'right';
                app.FilenameEditFieldLabel.Position = [7 95 62 22];
                app.FilenameEditFieldLabel.Text = 'File name:';

                % Create FilenameEditField
                app.FilenameEditField = uieditfield(app.PlotPanel, 'text');
                app.FilenameEditField.Position = [84 95 235 22];
                app.FilenameEditField.Value = app.measurementObj.fileName;
            else
                app.FilenameEditFieldLabel.delete;
                app.FilenameEditField.delete;
            end
        end

        function toggleComponents(app, enableFlag)
            % enableFlag is either true (Enable=on) or false (enable=off).
            components = [app.OpenfolderButton,  app.GetmeasurementButton];

            set(components, 'Enable', enableFlag)
        end

        function callAnalyzerApp(app,src,event)
            app.networkAnalyzerApp = networkAnalyzer(app);
        end
        
        function DropDownSelectMeasurementPlotValueChanged(app, event)
            measurementNames = cellfun(@(x) x.fileName,app.measurementObj,'UniformOutput',false);
            for i=1:length(measurementNames)
                if contains(lower(measurementNames{i}),lower(app.DropDownSelectMeasurementEth.Value))
                    app.chosenFileId = i;
                end
            end
            removeElements(app)
            if  isa(app.measurementObj{app.chosenFileId},'scope')
                buildAppScope(app)
            else
                buildAppPcap(app)
            end
            
            informationPanel(app)
            
        end

        function  informationPanel(app)
            % Create InformationPanel
            app.InformationPanel = uipanel(app.LeftPanel);
            app.InformationPanel.Title = 'Information';
            app.InformationPanel.Position = [30 60 307 215];

            % Create FilenameLabel
            app.FilenameLabel = uilabel(app.InformationPanel);
            app.FilenameLabel.Position = [16 172 58 22];
            app.FilenameLabel.Text = 'Filename:';

            % Create Filename
            app.Filename = uilabel(app.InformationPanel);
            app.Filename.Position = [79 162 220 45];
            app.Filename.WordWrap = true;


            % Create scopeLampLabel
            app.scopeLampLabel = uilabel(app.InformationPanel);
            app.scopeLampLabel.HorizontalAlignment = 'right';
            app.scopeLampLabel.Position = [13 133 38 22];
            app.scopeLampLabel.Text = 'scope';

            % Create scopeLamp
            app.scopeLamp = uilamp(app.InformationPanel);
            app.scopeLamp.Position = [66 133 20 20];

            % Create pcapLampLabel
            app.pcapLampLabel = uilabel(app.InformationPanel);
            app.pcapLampLabel.HorizontalAlignment = 'right';
            app.pcapLampLabel.Position = [171 132 32 22];
            app.pcapLampLabel.Text = 'pcap';


            % Create pcapLamp
            app.pcapLamp = uilamp(app.InformationPanel);
            app.pcapLamp.Position = [218 132 20 20];


            % Create RecordLengthEditFieldLabel
            app.RecordLengthEditFieldLabel = uilabel(app.InformationPanel);
            app.RecordLengthEditFieldLabel.HorizontalAlignment = 'Left';
            app.RecordLengthEditFieldLabel.Position = [13 96 118 22];
            app.RecordLengthEditFieldLabel.Text = 'Measurement length:';

            % Create RecordLengthEditField
            app.RecordLengthEditField = uieditfield(app.InformationPanel, 'numeric');
            app.RecordLengthEditField.Editable = 'off';
            app.RecordLengthEditField.Position = [142 96 100 22];

            % Create StarttimeEditFieldLabel
            app.StarttimeEditFieldLabel = uilabel(app.InformationPanel);
            app.StarttimeEditFieldLabel.HorizontalAlignment = 'left';
            app.StarttimeEditFieldLabel.Position = [13 66 80 22];
            app.StarttimeEditFieldLabel.Text = 'Start time [s]:';

            % Create StarttimeEditField
            app.StarttimeEditField = uieditfield(app.InformationPanel, 'numeric');
            app.StarttimeEditField.Editable = 'off';
            app.StarttimeEditField.Position = [142 66 100 22];

            % Create EndtimeEditFieldLabel
            app.EndtimeEditFieldLabel = uilabel(app.InformationPanel);
            app.EndtimeEditFieldLabel.HorizontalAlignment = 'left';
            app.EndtimeEditFieldLabel.Position = [13 38 70 22];
            app.EndtimeEditFieldLabel.Text = 'End time [s]:';

            % Create EndtimeEditField
            app.EndtimeEditField = uieditfield(app.InformationPanel, 'numeric');
            app.EndtimeEditField.Editable = 'off';
            app.EndtimeEditField.Position = [142 38 100 22];

            if ~isempty(app.chosenFileId)
                if  isa(app.measurementObj{app.chosenFileId},'scope')
                    app.Filename.Text = app.measurementObj{app.chosenFileId}.fileName;
                    app.pcapLamp.Color = [119,136,153]/255 ;
                    app.scopeLamp.Color = [0 1 0];
                    % Create SampleintervalEditFieldLabel
                    app.SampleintervalEditFieldLabel = uilabel(app.InformationPanel);
                    app.SampleintervalEditFieldLabel.HorizontalAlignment = 'left';
                    app.SampleintervalEditFieldLabel.Position = [13 9 118 22];
                    app.SampleintervalEditFieldLabel.Text = 'Sample interval [s]:';

                    % Create SampleintervalEditField
                    app.SampleintervalEditField = uieditfield(app.InformationPanel, 'numeric');
                    app.SampleintervalEditField.Editable = 'off';

                    app.SampleintervalEditField.Position = [142 9 100 22];
                    app.SampleintervalEditField.Value = app.measurementObj{app.chosenFileId}.sample_interval;

                    app.RecordLengthEditField.Value = app.measurementObj{app.chosenFileId}.sample_length;
                    app.StarttimeEditField.Value = app.measurementObj{app.chosenFileId}.time(1);
                    app.EndtimeEditField.Value = app.measurementObj{app.chosenFileId}.time(end);
                elseif isa(app.measurementObj{app.chosenFileId},'pcapMeasurement')
                    app.Filename.Text =app.measurementObj{app.chosenFileId}.fileName;
                    app.pcapLamp.Color = [0 1 0];
                    app.scopeLamp.Color = [119,136,153]/255;
                    numberOfPackets = 0;
                    startTime = zeros(1,length(app.measurementObj{app.chosenFileId}));
                    endTime = zeros(1,length(app.measurementObj{app.chosenFileId}));
                    for i = 1:length(app.measurementObj{app.chosenFileId}.port)
                        numberOfPackets = numberOfPackets + length(app.measurementObj{app.chosenFileId}.port(i).packet);
                        if ~isempty(app.measurementObj{app.chosenFileId}.port(i).packet)
                        startTime(i) = app.measurementObj{app.chosenFileId}.port(i).packet(1).time;
                        endTime(i) = app.measurementObj{app.chosenFileId}.port(i).packet(end).time;
                        else
                            startTime(i) = nan;
                            endTime(i) = nan;
                        end
                    end
                    
                    app.RecordLengthEditField.Value = numberOfPackets;
                    app.StarttimeEditField.Value = min(startTime);
                    app.EndtimeEditField.Value = max(endTime);
                else
                    app.pcapLamp.Color = [119,136,153]/255;
                    app.scopeLamp.Color = [119,136,153]/255;
                    app.RecordLengthEditField.Value = 0;
                    app.StarttimeEditField.Value = 0;
                    app.EndtimeEditField.Value = 0;
                end
            else
                app.Filename.Text = "No measurement retrieved";
                app.pcapLamp.Color = [119,136,153]/255;
                app.scopeLamp.Color = [119,136,153]/255;
                app.RecordLengthEditField.Value = 0;
                app.StarttimeEditField.Value = 0;
                app.EndtimeEditField.Value = 0;
            end
        end
    
        function removeElements(app)
                for i =1:numel(app.plotChoices)
                    delete(app.plotChoices(i).checkbox);
                    if isfield(app.plotChoices(i),'titleLabel')
                        delete(app.plotChoices(i).titleLabel);
                        delete(app.plotChoices(i).TitleEditField);
                    end
                end
              
                delete(app.saveBox);
                delete(app.PlotButton);
                delete(app.FFTButton);
                delete(app.PNplotButton);
                delete(app.pnDecodeBox);
                delete(app.speDecodeBox);
                delete(app.startPlotPacketLabel);
                delete(app.startPlotPacketNr);
                delete(app.endPlotPacketLabel);
                delete(app.endPlotPacketNr);
                delete(app.pnDecodePanel);
                delete(app.speDecodePanel);
                delete(app.speDecodeBox);
                delete(app.PBDecodePanel);
                delete(app.PBDecodeBox);
                delete(app.PBplotButton);
                delete(app.DecodeButton);
                delete(app.FFTPanel);
                delete(app.PlotPanel_2);
                delete(app.channelPanel);
                delete(app.mathPanel);
                delete(app.ChannelSelection);
                app.plotChoices = [];
                delete(app.ethernetTableButton);
                delete(app.pbTableButton);
                if length(app.pnDecodeBox)>1
                    app.pnDecodeBox(2:end) = [];
                end
                if length(app.speDecodeBox)>1                     
                    app.speDecodeBox(2:end) = [];
                end
               
        end
        
        function buildAppPcap(app)
            % Create DecodePanel
                app.DecodePanel = uipanel(app.GridLayout);
                app.DecodePanel.Title = 'Ethernet section';
                app.DecodePanel.Layout.Row = 1;
                app.DecodePanel.Layout.Column = 3;
                app.DecodePanel.FontWeight = 'bold';
                app.DecodePanel.FontSize = 16;
                maxPackets = 0;
                startID = find(cellfun(@isempty,{app.measurementObj{app.chosenFileId}.port.packet})==0 ,1,'first');
                for ii =startID: numel(app.measurementObj{app.chosenFileId}.port)
                    app.pnDecodeBox(ii-(startID-1)) = uicheckbox(app.DecodePanel);
                    app.pnDecodeBox(ii-(startID-1)).Position = [12 368-(30*(ii-(startID-1))) 150 22];
                    app.pnDecodeBox(ii-(startID-1)).Text = [app.measurementObj{app.chosenFileId}.port(ii).packet(1).frame.encapsulation_desc ' port ' num2str(ii-1)];
                    maxPackets  = maxPackets + numel(app.measurementObj{app.chosenFileId}.port(ii).packet);

                end

                % Create packet range Field
                app.startPlotPacketLabel = uilabel(app.DecodePanel);
                app.startPlotPacketLabel.Position = [20 120 100 22];
                app.startPlotPacketLabel.Text = 'Plot packets from';
                app.startPlotPacketNr = uieditfield(app.DecodePanel, 'numeric');
                app.startPlotPacketNr.Position = [120 120 50 22];

                app.endPlotPacketLabel = uilabel(app.DecodePanel);
                app.endPlotPacketLabel.Position = [180 120 20 22];
                app.endPlotPacketLabel.Text = 'to';
                app.endPlotPacketNr = uieditfield(app.DecodePanel, 'numeric');
                app.endPlotPacketNr.Position = [200 120 50 22];
                app.endPlotPacketNr.Value= maxPackets;


                % Create plot PN button

                app.PNplotButton = uibutton(app.DecodePanel, 'push');
                app.PNplotButton.Position = [28 90 100 22];
                app.PNplotButton.Text = 'plot';
                %                 app.PNplotButton.BusyAction = 'cancel';
                %                 app.PNplotButton.Interruptible = 'off';
                app.PNplotButton.ButtonPushedFcn = @app.pcapPlot;

                app.ethAnalyzeButton = uibutton(app.DecodePanel, 'push');
                app.ethAnalyzeButton.Position = [28 60 100 22];
                app.ethAnalyzeButton.Text = 'Analyze app';
                app.ethAnalyzeButton.ButtonPushedFcn = @app.callAnalyzerApp;
                
                app.ethernetTableButton = uibutton(app.DecodePanel, 'push');
                app.ethernetTableButton.Position = [135 90 100 22];
                app.ethernetTableButton.Text = 'table';
                app.ethernetTableButton.ButtonPushedFcn = @app.ethernetTable;
        end
 
        function ethernetTable(app,src,event)
            app.measurementObj{app.chosenFileId}.table
        end
        function pbTable(app,src,event)
            for i =1:length(app.measurementObj{app.chosenFileId}.channels)
                if isprop(app.measurementObj{app.chosenFileId}.channels(i),'pb')
                    app.measurementObj{app.chosenFileId}.channels(i).pb.table();
                end
            end
        end
        
        function buildAppScope(app)
            i=1;
                % Create checkboxes to enable plot for each channel/math of
                % the measurement

                app.ChannelSelection = uilabel(app.PlotPanel);
                app.ChannelSelection.HorizontalAlignment = 'left';
                app.ChannelSelection.Position = [10 app.screenHeight*0.85 250 22];
                app.ChannelSelection.Text = 'Select channels and maths:';
                app.ChannelSelection.FontWeight = 'bold';
                
                for channelName = {app.measurementObj{app.chosenFileId}.channels.name}
                    app.plotChoices(i).checkbox = uicheckbox(app.PlotPanel);
                    app.plotChoices(i).checkbox.Position = [10 app.screenHeight*0.85-(30*i) 80 22];
                    app.plotChoices(i).checkbox.Text = channelName;
                    app.plotChoices(i).checkbox.ValueChangedFcn = {@app.plotEnable,i};
                    i = i +1;
                end
                if isprop(app.measurementObj{app.chosenFileId},'math')                
                    for mathChannel = {app.measurementObj{app.chosenFileId}.math.channel}
                        channelName = mathChannel{1}.name;
                        app.plotChoices(i).checkbox = uicheckbox(app.PlotPanel);
                        app.plotChoices(i).checkbox.Position = [10 app.screenHeight*0.85-(30*i) 80 22];
                        app.plotChoices(i).checkbox.Text = channelName;
                        app.plotChoices(i).checkbox.ValueChangedFcn = {@app.plotEnable,i};
                        i = i +1;
                    end
                end
                % Create save checkbox
                app.saveBox = uicheckbox(app.PlotPanel);
                app.saveBox.Position = [10 app.screenHeight*0.85-(30*i)-20 80 22];
                app.saveBox.Text = 'Save plots';
                app.saveBox.FontWeight = 'bold';
                app.saveBox.ValueChangedFcn = @app.saveOption;
                % Remove store name field
                app.FilenameEditFieldLabel.delete;
                app.FilenameEditField.delete;

                % ------- Time limit plot function -----
                % Create PlotPanel_2
                app.PlotPanel_2 = uipanel(app.PlotPanel);
                app.PlotPanel_2.Title = 'Plot';
                app.PlotPanel_2.Position = [app.PlotPanel.Position(3)*0.05,58,app.PlotPanel.Position(3)*0.4,185];

                app.channelPlotRangeLabel= uilabel(app.PlotPanel_2);
                app.channelPlotRangeLabel.Position = [12 140 60 22];
                app.channelPlotRangeLabel.Text = 'Channels:';
                app.channelPlotRangeLabel.FontWeight = 'bold'; 
                
                % Create StarttimeLabel
                app.StarttimeLabel = uilabel(app.PlotPanel_2);
                app.StarttimeLabel.HorizontalAlignment = 'right';
                app.StarttimeLabel.Position = [12 110 60 22];
                app.StarttimeLabel.Text = 'Start time:';

                % Create StarttimeSpinner
                app.StarttimeSpinner = uispinner(app.PlotPanel_2);
                app.StarttimeSpinner.Position = [73 110 200 22];
                app.StarttimeSpinner.Value = app.measurementObj{app.chosenFileId}.time(1);

                % Create EndtimeLabel
                app.EndtimeLabel = uilabel(app.PlotPanel_2);
                app.EndtimeLabel.HorizontalAlignment = 'right';
                app.EndtimeLabel.Position = [12 80 56 22];
                app.EndtimeLabel.Text = 'End time:';

                % Create EndtimeSpinner
                app.EndtimeSpinner = uispinner(app.PlotPanel_2);
                app.EndtimeSpinner.Position = [73 80 200 22];
                app.EndtimeSpinner.Value = app.measurementObj{app.chosenFileId}.time(end);     

                % Create PlotButton
                app.PlotButton = uibutton(app.PlotPanel_2, 'push');
                app.PlotButton.Position = [190,10,100,22];
                app.PlotButton.Text = 'Plot';
                %                 app.PlotButton.BusyAction = 'cancel';
                %                 app.PlotButton.Interruptible = 'off';
                app.PlotButton.ButtonPushedFcn = @app.plotting;


                % ------- Advanced FFT function -----
                % Create PlotPanel_2
                app.FFTPanel= uipanel(app.PlotPanel);
                app.FFTPanel.Title = 'FFT';
                app.FFTPanel.Position = [app.PlotPanel.Position(3)*0.55,58,app.PlotPanel.Position(3)*0.4,185];

                % Create StarttimeLabel
                app.FFTStarttimeLabel = uilabel(app.FFTPanel);
                app.FFTStarttimeLabel.HorizontalAlignment = 'right';
                app.FFTStarttimeLabel.Position = [12 130 60 22];
                app.FFTStarttimeLabel.Text = 'Start time:';

                % Create StarttimeSpinner
                app.FFTStarttimeSpinner = uispinner(app.FFTPanel);
                app.FFTStarttimeSpinner.Position = [73 130 200 22];
                app.FFTStarttimeSpinner.Value = app.measurementObj{app.chosenFileId}.time(1);

                % Create EndtimeLabel
                app.FFTEndtimeLabel = uilabel(app.FFTPanel);
                app.FFTEndtimeLabel.HorizontalAlignment = 'right';
                app.FFTEndtimeLabel.Position = [12 90 56 22];
                app.FFTEndtimeLabel.Text = 'End time:';

                % Create EndtimeSpinner
                app.FFTEndtimeSpinner = uispinner(app.FFTPanel);
                app.FFTEndtimeSpinner.Position = [73 90 200 22];
                app.FFTEndtimeSpinner.Value = app.measurementObj{app.chosenFileId}.time(end);

                % Create WindowDropDownLabel
                app.WindowDropDownLabel = uilabel(app.FFTPanel);
                app.WindowDropDownLabel.HorizontalAlignment = 'right';
                app.WindowDropDownLabel.Position = [12 50 55 22];
                app.WindowDropDownLabel.Text = 'Window: ';app.selectedFile

                % Create WindowDropDown
                app.WindowDropDown = uidropdown(app.FFTPanel);
                app.WindowDropDown.Items = {'hanning', 'hamming', 'rectangle', 'blackmanharris', 'gaussian'};
                app.WindowDropDown.Position = [73 50 100 22];
                app.WindowDropDown.Value = 'hanning';

                % Create FFTButton
                app.FFTButton = uibutton(app.FFTPanel, 'push');
                app.FFTButton.Position = [190 10 100 22];
                app.FFTButton.Text = 'FFT';
                %                 app.FFTButton.BusyAction = 'cancel';
                %                 app.FFTButton.Interruptible = 'off';
                app.FFTButton.ButtonPushedFcn = @app.plotFFT;

                % ---------- Create DecodePanel --------
                app.DecodePanel = uipanel(app.GridLayout);
                app.DecodePanel.Title = 'Ethernet section';
                app.DecodePanel.Layout.Row = 1;
                app.DecodePanel.Layout.Column = 3;
                app.DecodePanel.FontWeight = 'bold';
                app.DecodePanel.FontSize = 16;
                i=1;
                
                numberOfChannels =length(app.plotChoices);
                
                app.pnDecodePanel= uipanel(app.DecodePanel);
                app.pnDecodePanel.Position = [10 (app.screenHeight*0.85-30*numberOfChannels) 120 30*numberOfChannels+20];
                app.pnDecodePanel.Title = 'PROFINET';
                
                app.speDecodePanel= uipanel(app.DecodePanel);
                app.speDecodePanel.Position = [140 (app.screenHeight*0.85-30*numberOfChannels) 120 30*numberOfChannels+20];
                app.speDecodePanel.Title = 'Single Pair Ethernet';

                app.PBDecodePanel= uipanel(app.DecodePanel);
                app.PBDecodePanel.Position = [270 (app.screenHeight*0.85-30*numberOfChannels) 120 30*numberOfChannels+20];
                app.PBDecodePanel.Title = 'PROFIBUS DP';

                for channelName = {app.measurementObj{app.chosenFileId}.channels.name}
                                        
                    app.pnDecodeBox(i) = uicheckbox(app.pnDecodePanel);
                    app.pnDecodeBox(i).Position = [5 30*numberOfChannels-(30*i) 80 22];                  
                    app.pnDecodeBox(i).Text = channelName;

                    % SPE section
                    app.speDecodeBox(i) = uicheckbox(app.speDecodePanel);
                    app.speDecodeBox(i).Position = [5 30*numberOfChannels-(30*i) 80 22];
                    app.speDecodeBox(i).Text =channelName;

                    % PB section
                    app.PBDecodeBox(i) = uicheckbox(app.PBDecodePanel);
                    app.PBDecodeBox(i).Position = [5 30*numberOfChannels-(30*i) 80 22];
                    app.PBDecodeBox(i).Text =channelName;

                    i = i +1;
                end
                if isprop(app.measurementObj{app.chosenFileId},'math')  
                    for mathChannel = {app.measurementObj{app.chosenFileId}.math.channel}
                        channelName = mathChannel{1}.name;          
                        app.pnDecodeBox(i) = uicheckbox(app.pnDecodePanel);
                        app.pnDecodeBox(i).Position = [5 30*numberOfChannels-(30*i) 80 22];                  
                        app.pnDecodeBox(i).Text = channelName;
    
                        % SPE section
                        app.speDecodeBox(i) = uicheckbox(app.speDecodePanel);
                        app.speDecodeBox(i).Position = [5 30*numberOfChannels-(30*i) 80 22];
                        app.speDecodeBox(i).Text =channelName;
                        i = i +1;
                    end
                end
                % Create DecodeButton
                app.DecodeButton = uibutton(app.DecodePanel, 'push');
                app.DecodeButton.Position = [28 37 100 22];
                app.DecodeButton.Text = 'Decode';
                %                 app.DecodeButton.BusyAction = 'cancel';
                %                 app.DecodeButton.Interruptible = 'off';
                app.DecodeButton.ButtonPushedFcn = @app.decode;
                
                
        end
        
        function fullFilenames = getCorrespondingMeasurementFilenames(app,filename)
            findCorrespondingFileIDs = contains(lower(app.files),lower(strcat(filename,"_ch")));
                    findCorrespondingFileIDs = findCorrespondingFileIDs | contains(lower(app.files),lower(strcat(filename,"ch")));
                    findCorrespondingFileIDs = findCorrespondingFileIDs | contains(lower(app.files),lower(strcat(filename,"_math")));
                    findCorrespondingFileIDs = findCorrespondingFileIDs | contains(lower(app.files),lower(strcat(filename,"mth")));
                    findCorrespondingFileIDs = findCorrespondingFileIDs | matches(lower(app.files),lower(strcat(filename,".pcapng")));
                    findCorrespondingFileIDs = findCorrespondingFileIDs | matches(lower(app.files),lower(strcat(filename,".pcap")));
                    
                    fullFilenames = app.files(findCorrespondingFileIDs);
            
        end
        
        function pcapQuickAnalyze(app,event)
            measurmentRetrieved = true;


            GetmeasurementButtonPushed(app,0);
            enableHistogram = contains(app.EnableHistogramSwitch.Value,'On');
            deviceName=app.DeviceNameEditField.Value;
            TAPport1 = str2num(app.Tap1portsEditField.Value);
            TAPport2 =  str2num(app.Tap2portsEditField.Value);
            if contains(app.DirectionCPUSwitch.Value,'Up-')
                direction = 'descend';
            else
                direction = 'ascend';
            end
            enableDistribution = contains(app.EnabledistributionSwitch.Value,'On');


            set(app.MeasurementappUIFigure,'Pointer','watch'); drawnow;
            toggleComponents(app,'Off');
            NetworkDevice.quickAnalyze(app.measurementObj,app.folder,enableHistogram,deviceName,TAPport1,TAPport2,direction,app.BinSizeEditField.Value,enableDistribution);
            toggleComponents(app,'On');
            set(app.MeasurementappUIFigure,'Pointer','arrow'); drawnow;
        end
        
        function chosenMeasurementDirection(app,event)
            if contains(event.Value,'Bi')
                app.DirectionCPUSwitch.Enable = false;
                app.DirectionCPUSwitch.Value = 'Upstream';
            else
               app.DirectionCPUSwitch.Enable = true;
            end
                
        end
        
        function buildQuickAnalyzePcapPanel(app,enable)
            if enable == 0                 
                % quick analyze panel
                delete(app.QuickpcapanalyzerPanel);
                delete(app.analyzeButton);
                delete(app.EnabledistributionSwitch);
                delete(app.EnabledistributionSwitchLabel);
                delete(app.DirectionCPUSwitch);
                delete(app.DirectionCPUSwitchLabel);
                delete(app.Tap2portsEditField);
                delete(app.Tap2portsEditFieldLabel);
                delete(app.Tap1portsEditField);
                delete(app.Tap1portsEditFieldLabel);
                delete(app.DirectionalSwitch);
                delete(app.DirectionalSwitchLabel);
                delete(app.EnableHistogramSwitch);
                delete(app.EnableHistogramSwitchLabel);
                delete(app.DeviceNameEditFieldLabel);
                delete(app.DeviceNameEditField);
                delete(app.BinSizeEditField);
                delete(app.BinSizeEditFieldLabel);
                return;
            end
            % Create QuickpcapanalyzerPanel
            app.QuickpcapanalyzerPanel = uipanel(app.LeftPanel);
            app.QuickpcapanalyzerPanel.Title = 'Quick pcap analyzer';
            app.QuickpcapanalyzerPanel.Position = [30 284 307 250];
            
            % Create EnableHistogramSwitchLabel
            app.EnableHistogramSwitchLabel = uilabel(app.QuickpcapanalyzerPanel);
            app.EnableHistogramSwitchLabel.HorizontalAlignment = 'center';
            app.EnableHistogramSwitchLabel.FontWeight = 'bold';
            app.EnableHistogramSwitchLabel.Position = [8 207 114 22];
            app.EnableHistogramSwitchLabel.Text = 'Enable histogram';
            
            % Create EnableHistogramSwitch
            
            app.EnableHistogramSwitch = uiswitch(app.QuickpcapanalyzerPanel, 'slider');
            app.EnableHistogramSwitch.Items = {'On','Off'};
            app.EnableHistogramSwitch.Position = [41 187 35 20];
            app.EnableHistogramSwitch.Orientation = 'horizontal';
            app.EnableHistogramSwitch.Value= 'On';
            
                % Create DeviceNameEditFieldLabel
            app.DeviceNameEditFieldLabel = uilabel(app.QuickpcapanalyzerPanel);
            app.DeviceNameEditFieldLabel.HorizontalAlignment = 'center';
            app.DeviceNameEditFieldLabel.FontWeight = 'bold';
            app.DeviceNameEditFieldLabel.Position = [150 207 100 22];
            app.DeviceNameEditFieldLabel.Text = 'Device name:';

            % Create DeviceNameEditField
            app.DeviceNameEditField = uieditfield(app.QuickpcapanalyzerPanel, 'text');
            app.DeviceNameEditField.Position = [150 184 150 22];
            if isempty(app.DeviceNameEditField.Value)
                app.DeviceNameEditField.Value = "[cpu1516-131.x1]";
            end

            % Create DirectionalSwitchLabel
            app.DirectionalSwitchLabel = uilabel(app.QuickpcapanalyzerPanel);
            app.DirectionalSwitchLabel.HorizontalAlignment = 'center';
            app.DirectionalSwitchLabel.FontWeight = 'bold';
            app.DirectionalSwitchLabel.Position = [25 157 68 22];
            app.DirectionalSwitchLabel.Text = 'Directional';

            % Create DirectionalSwitch
            app.DirectionalSwitch = uiswitch(app.QuickpcapanalyzerPanel, 'slider');
            app.DirectionalSwitch.Items = {'Bi-', 'One-'};
            app.DirectionalSwitch.Position = [41 137 35 20];
%             app.DirectionalSwitch.Value = 'Bi-';
            app.DirectionalSwitch.ValueChangedFcn = createCallbackFcn(app, @chosenMeasurementDirection, true);
            
             % Create DirectionCPUSwitchLabel
            app.DirectionCPUSwitchLabel = uilabel(app.QuickpcapanalyzerPanel);
            app.DirectionCPUSwitchLabel.HorizontalAlignment = 'center';
            app.DirectionCPUSwitchLabel.FontWeight = 'bold';
            app.DirectionCPUSwitchLabel.Position = [195 157 86 22];
            app.DirectionCPUSwitchLabel.Text = 'Direction CPU';

            % Create DirectionCPUSwitch
            app.DirectionCPUSwitch = uiswitch(app.QuickpcapanalyzerPanel, 'slider');
            app.DirectionCPUSwitch.Items = {'Up-', 'Downstream'};
            app.DirectionCPUSwitch.Position = [185 137 35 20];
%             app.DirectionCPUSwitch.Value = 'Up-';
            

            % Create Tap1portsEditFieldLabel
            app.Tap1portsEditFieldLabel = uilabel(app.QuickpcapanalyzerPanel);
            app.Tap1portsEditFieldLabel.HorizontalAlignment = 'right';
            app.Tap1portsEditFieldLabel.Position = [8 102 73 22];
            app.Tap1portsEditFieldLabel.Text = 'Tap 1 port(s):';

            % Create Tap1portsEditField
            app.Tap1portsEditField = uieditfield(app.QuickpcapanalyzerPanel, 'text');
            app.Tap1portsEditField.Position = [85 102 45 22];
            if isempty(app.Tap1portsEditField.Value)
                app.Tap1portsEditField.Value = "2 3";
            end
            
            % Create Tap2portsEditFieldLabel
            app.Tap2portsEditFieldLabel = uilabel(app.QuickpcapanalyzerPanel);
            app.Tap2portsEditFieldLabel.HorizontalAlignment = 'right';
            app.Tap2portsEditFieldLabel.Position = [155 102 73 22];
            app.Tap2portsEditFieldLabel.Text = 'Tap 2 port(s):';

            % Create Tap2portsEditField
            app.Tap2portsEditField = uieditfield(app.QuickpcapanalyzerPanel, 'text');
            app.Tap2portsEditField.Position = [235 102 45 22];
            if isempty(app.Tap2portsEditField.Value)
                app.Tap2portsEditField.Value = "0 1"; 
            end

              % Create BinSizeEditFieldLabel
            app.BinSizeEditFieldLabel = uilabel(app.QuickpcapanalyzerPanel);
            app.BinSizeEditFieldLabel.HorizontalAlignment = 'right';
            app.BinSizeEditFieldLabel.Position = [8 72 73 22];
            app.BinSizeEditFieldLabel.Text = 'Bin size:';

            % Create BinSizeEditField
            app.BinSizeEditField = uieditfield(app.QuickpcapanalyzerPanel, 'numeric');
            app.BinSizeEditField.Position = [85 72 45 22];
            if isempty(app.BinSizeEditField.Value) || app.BinSizeEditField.Value==0
                app.BinSizeEditField.Value = 100e-9; 
            end
            
            % Create EnabledistributionSwitchLabel
            app.EnabledistributionSwitchLabel = uilabel(app.QuickpcapanalyzerPanel);
            app.EnabledistributionSwitchLabel.HorizontalAlignment = 'center';
            app.EnabledistributionSwitchLabel.FontWeight = 'bold';
            app.EnabledistributionSwitchLabel.Position = [8 7 114 22];
            app.EnabledistributionSwitchLabel.Text = 'Enable distribution';

            % Create EnabledistributionSwitch
            app.EnabledistributionSwitch = uiswitch(app.QuickpcapanalyzerPanel, 'slider');
            app.EnabledistributionSwitch.Items = {'On', 'Off'};
            app.EnabledistributionSwitch.Position = [50 32 35 20];
%             app.EnabledistributionSwitch.Value = 'On';

            % Create analyzeButton
            app.analyzeButton = uibutton(app.QuickpcapanalyzerPanel, 'push');
            app.analyzeButton.ButtonPushedFcn = createCallbackFcn(app, @pcapQuickAnalyze, true);
            app.analyzeButton.Position = [163 25 100 22];
            app.analyzeButton.Text = 'analyze';
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            drawnow;
            app.MeasurementappUIFigure.WindowState = 'maximized';
                          app.screenSize = get(groot,'ScreenSize');
              app.screenWidth = app.screenSize(3);
              app.screenHeight = app.screenSize(4);
        end

        % Button pushed function: OpenfolderButton
        function OpenfolderButtonPushed(app, event)
            toggleComponents(app,'Off')
            pathUrl = fullfile(userpath, "lastPath.mat");
            if isfile(pathUrl)
                load(pathUrl);
            else
                lastPath = "";
            end
            app.folder = uigetdir(lastPath);


            drawnow;
            figure(app.MeasurementappUIFigure)
            if(app.folder ~= 0)
                lastPath = string(app.folder);
                a=app.Tree.Children;
                a.delete;

                filesAndFolders = dir(app.folder);
                % ...then exclude the folders, to get just a list of files.
                fls = filesAndFolders(~[filesAndFolders.isdir]);
                fls = {fls.name};
                uniqueFiles =[];
                app.files=fls(contains(fls,["wfm","isf",'pcap','pcapng']));
                if sum(contains(app.files,"wfm"))>0
                    wfmFiles = app.files(contains(app.files,'wfm'));
                    lastUnderscore = cellfun(@(x) find(x== '_', 1, 'last'),wfmFiles);
                    uniqueFiles = unique( extractBefore(wfmFiles,lastUnderscore));
                end
                if sum(contains(app.files,"isf"))>0
                    isfFiles =  app.files(contains(app.files,'isf'));
                    lastUnderscore = cellfun(@(x) find(x== '.', 1, 'last'),isfFiles)-3;
                    uniqueFiles = [uniqueFiles unique( extractBefore(isfFiles,lastUnderscore))];
                end
                if sum(contains(app.files,"pcap"))>0
                    pcapFiles =  app.files(contains(app.files,'pcap'));
                    lastUnderscore = cellfun(@(x) find(x== '.', 1, 'last'),pcapFiles);
                    uniqueFiles = [uniqueFiles unique( extractBefore(pcapFiles,lastUnderscore))];
                end
                %                 if sum(contains(app.files,"pcapng"))>0
                %                     lastUnderscore = cellfun(@(x) find(x== '.', 1, 'last'),app.files)-6;
                %                     uniqueFiles = [uniqueFiles unique( extractBefore(app.files,lastUnderscore))];
                %                 end

                if ~exist('lastUnderscore','var') || isempty(lastUnderscore)
                    toggleComponents(app,'On')
                    return;
                end

                %                 uniqueFiles = unique( extractBefore(app.files,lastUnderscore));
                % Catch difference windows and mac path divider
                if ispc
                    slash = '\';
                else
                    slash = '/';
                end
                % Create Node
                app.Foldertree = uitreenode(app.Tree);
                app.Foldertree.Text =extractAfter(app.folder,find(app.folder== slash, 1, 'last'));
                % Create Node
                for i =1: numel(uniqueFiles)
                    app.Node(i) = uitreenode(app.Foldertree);
                    app.Node(i).Text = uniqueFiles{i};
                end
            else
                app.folder = lastPath;
            end
            toggleComponents(app,'On')

            save(pathUrl, "lastPath");
            clear lastPath fls uniqueFiles slash pathUrl
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
             app.selectedFile = {selectedNodes.Text};
            measurementFilenames = getCorrespondingMeasurementFilenames(app,app.selectedFile);
            % quick analyze panel
                
            if sum(contains(measurementFilenames,'pcap')) == length(measurementFilenames)
               buildQuickAnalyzePcapPanel(app,1);             
            else
                buildQuickAnalyzePcapPanel(app,0);   
            end
%             
           %app.files(chIndices | mathIndices | pcapIndices);
        end

        % Button pushed function: GetmeasurementButton
        function GetmeasurementButtonPushed(app, event)

            set(app.MeasurementappUIFigure,'Pointer','watch'); drawnow;
            toggleComponents(app,'Off');
            %             delete(app.measuremetDoneLabel);
            delete(app.decodeDoneLabel);
            delete(app.startPlotPacketLabel);
            delete(app.startPlotPacketNr);
            delete(app.endPlotPacketLabel);
            delete(app.endPlotPacketNr);
            %               app.measuremetDoneLabel = uilabel(app.LeftPanel);
            %                 %             app.measuremetDoneLabel.HorizontalAlignment = 'right';
            %                 app.measuremetDoneLabel.Position = [60 50 150 22];

            %             try
            if isempty(app.selectedFile)
                toggleComponents(app,'On');
                set(app.MeasurementappUIFigure,'Pointer','arrow'); drawnow;
                return;
            else
                for fileID=1:length(app.selectedFile)                    
                   fullFilenames = getCorrespondingMeasurementFilenames(app,app.selectedFile{fileID});
                   measurmentRetrieved = false;
                   
                    for i=1: length(app.measurementObj)
                        measurmentRetrieved = measurmentRetrieved | sum(contains(fullFilenames, app.measurementObj{i}.fileName))==1;
                    end 
                    
                    if ~measurmentRetrieved
                        measurementID = length(app.measurementObj)+1;
                        if ~isempty(fullFilenames) & contains(fullFilenames,'wfm')
                            app.measurementObj{measurementID} = scope.wfmread(fullFilenames,0,"app",app.folder);
                        elseif ~isempty(fullFilenames) & (contains(fullFilenames,'pcap') | contains(fullFilenames,'pcapng'))
                            app.measurementObj{measurementID} = pcapMeasurement(fullfile(app.folder,cell2mat(fullFilenames)));                        
                        else
                            app.measurementObj{measurementID} = scope.isfread(fullFilenames,0,"app",app.folder);
                        end
                    end
                end
            end
            
            if length(app.measurementObj)>1
                               
                % Create DropDownSelectMeasurementPlot
                app.LabelSelectMeasurementEth = uilabel(app.LeftPanel);
                app.LabelSelectMeasurementEth.Position = [10 540 209 22];
                app.LabelSelectMeasurementEth.Text = "Select measurement: ";
                
                app.DropDownSelectMeasurementEth = uidropdown(app.LeftPanel);
                app.DropDownSelectMeasurementEth.Items = cellfun(@(x) x.fileName,app.measurementObj,'UniformOutput',false);
                app.DropDownSelectMeasurementEth.ValueChangedFcn = createCallbackFcn(app, @DropDownSelectMeasurementPlotValueChanged, true);
                app.DropDownSelectMeasurementEth.Position = [130 540 230 22];
                app.DropDownSelectMeasurementEth.Value = app.measurementObj{1}.fileName;
            end

            %             app.CheckBox = uicheckbox(app.PlotPanel);
            %

            if isa(app.measurementObj{1},'scope')            
                removeElements(app);
                buildAppScope(app);
                

                assignin('base',"measurementObject",app.measurementObj);


            end

            if isa(app.measurementObj{1},'pcapMeasurement')

                removeElements(app);          
                buildAppPcap(app);
                
                assignin('base',"measurementObject",app.measurementObj);
            end
 

            %                 app.measuremetDoneLabel.Text = 'Done';
            app.clearMeasurementsButton.Visible = true;
            informationPanel(app);

            toggleComponents(app,'On');
            set(app.MeasurementappUIFigure,'Pointer','arrow'); drawnow;
            %             catch
            %                 informationPanel(app);
            %                 for i =1:numel(app.plotChoices)
            %                     delete(app.plotChoices(i).checkbox);
            %                 end
            %                 delete(app.saveBox);
            %                 delete(app.PlotButton);
            %                 delete(app.FFTButton);
            %                 delete(app.PNplotButton);
            %                 delete(app.pnDecodeBox);
            %                 delete(app.startPlotPacketLabel);
            %                 delete(app.startPlotPacketNr);
            %                 delete(app.endPlotPacketLabel);
            %                 delete(app.endPlotPacketNr);
            %                 delete(app.pnDecodeLabel);
            %                 delete(app.speDecodeLabel);
            %                 delete(app.speDecodeBox);
            %                 delete(app.DecodeButton);
            %                 toggleComponents(app,'On');
            %                 set(app.MeasurementappUIFigure,'Pointer','arrow'); drawnow;
            %             end
        end

        % Close request function: MeasurementappUIFigure
        function MeasurementappUIFigureCloseRequest(app, event)
            delete(app.networkAnalyzerApp)
            delete(app)

        end

        % Button pushed function: clearMeasurementsButton
        function clearMeasurementsButtonPushed(app, event)
            app.measurementObj = [];
            removeElements(app);  
            app.clearMeasurementsButton.Visible = false;
            delete(app.InformationPanel);
            delete(app.Filename);
            delete(app.SampleintervalEditField);
            delete(app.SampleintervalEditFieldLabel);
            delete(app.EndtimeEditField);
            delete(app.EndtimeEditFieldLabel);
            delete(app.StarttimeEditField);
            delete(app.StarttimeEditFieldLabel);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MeasurementappUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {1029, 1029};
                app.GridLayout.ColumnWidth = {'1x'};
                app.PlotPanel.Layout.Row = 2;
                app.PlotPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {365, '1x'};
                app.PlotPanel.Layout.Row = 1;
                app.PlotPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MeasurementappUIFigure = uifigure('Visible', 'off');
            app.MeasurementappUIFigure.Name = 'Measurement app';
            app.MeasurementappUIFigure.Icon = 'logo.jpg';
            app.MeasurementappUIFigure.CloseRequestFcn = createCallbackFcn(app, @MeasurementappUIFigureCloseRequest, true);
            app.MeasurementappUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.MeasurementappUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'Get measurement';
            app.OpenfolderButton = uibutton(app.LeftPanel, 'push');
            app.OpenfolderButton.ButtonPushedFcn = createCallbackFcn(app, @OpenfolderButtonPushed, true);
            app.OpenfolderButton.Text = 'Open folder';
            app.Tree = uitree(app.LeftPanel);
            app.Tree.Multiselect = 'on';
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.GetmeasurementButton = uibutton(app.LeftPanel, 'push');
            app.GetmeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @GetmeasurementButtonPushed, true);
            app.GetmeasurementButton.Text = 'Get measurement';
            app.clearMeasurementsButton = uibutton(app.LeftPanel, 'push');
            app.clearMeasurementsButton.ButtonPushedFcn = createCallbackFcn(app, @clearMeasurementsButtonPushed, true);
            app.clearMeasurementsButton.Icon = 'clearIcon.jpg';
            app.clearMeasurementsButton.Visible = 'off';
            app.clearMeasurementsButton.Text = '';
            app.KULeuvenLogo = uiimage(app.LeftPanel);
            app.KULeuvenLogo.ImageSource = 'KULEUVEN_CMYK_LOGO.png';
            app.CINIlogo = uiimage(app.LeftPanel);
            app.CINIlogo.ImageSource = 'CINI4.0_1920.png';
            app.PlotPanel = uipanel(app.GridLayout);
            app.PlotPanel.Title = 'Plot section';
            % Show the figure after all components are created
            app.MeasurementappUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = scopeApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MeasurementappUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MeasurementappUIFigure)
        end
    end
end